classdef applicazione < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterpolazioneUIFigure    matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        PLOTButton                matlab.ui.control.Button
        FunzioneEditFieldLabel    matlab.ui.control.Label
        FunzioneEditField         matlab.ui.control.EditField
        TipoButtonGroup           matlab.ui.container.ButtonGroup
        PolinomialeButton         matlab.ui.control.RadioButton
        PolinomialeatrattiButton  matlab.ui.control.RadioButton
        SplinenotaknotButton      matlab.ui.control.RadioButton
        SplinenaturaleButton      matlab.ui.control.RadioButton
        SplinecompletaButton      matlab.ui.control.RadioButton
        intervalloEditFieldLabel  matlab.ui.control.Label
        intervalloEditField       matlab.ui.control.NumericEditField
        numeronodiEditFieldLabel  matlab.ui.control.Label
        numeronodiEditField       matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        ErroreLabel               matlab.ui.control.Label
        bEditField                matlab.ui.control.NumericEditField
    end

    
    methods (Access = public)
        
        function [errore] = interpolazione(app,funzione,tipo,a,b,n_nodi)
    
    if n_nodi <= 1
        errordlg('Inserire almeno 2 nodi');
        error('Inserire almeno 2 nodi')
    end
            
    if b-a<=0
           errordlg('Inserire ampiezza intervallo consentita');
        error('Inserire ampiezza intervallo consentita')
    end
    
  
    % definisco l'intervallo della funzione lungo l'asse x tramite la funzione linespace, che
    % ci restituisce un vettore riga di 100 punti equidistanti tra i due estremi. 
            t = linspace(a,b);
     % valuto la mia funzione di ingresso nell'intervallo appena definito
            y1 = funzione(t);
            
    % Definiamo un vettore riga che rappresenta le ascisse x dei punti da interpolare
            
            x = linspace(a,b,n_nodi); 
            
            % Calcolo il valore delle ordinate dei punti di x valutati
            % nella funzione passata in ingresso
      
            y = funzione(x);
    
           % Ora a seconda della tipologia vado a completare le operazioni di interpolazione: 
            
            
    switch tipo
        case 'polinomiale'


            % Utilizzando la funzione polyfit è possibile ottenere in uscita i coefficienti del 
            % polinomio che meglio si adatta ai punti (x,y) nel senso dei minimi
            % quadrati
            
            p = polyfit(x,y,n_nodi-1);
            
            % La funzione polyval valuta il vettore appena ottenuto in ogni punto x
            y_out = polyval(p,t);
            
            
           % x y punti, t y_out funzione interpolata, t y1 funzione reale
            plot(app.UIAxes,t,y1,'r',t,y_out,'b',x,y,'ok');
            legend(app.UIAxes,'Funzione','Interpolazione Polinomiale','Punti')
            title(app.UIAxes,'Interpolazione polinomiale')
            
            
        case 'lineare'


            % generiamo una funzione interpolante a tratti lineare, 
            % quindi dati due punti viene tracciato il segmento che congiunge questi due punti.
            y_out = interp1(x,y,t);
            
            plot(app.UIAxes,t,y1,'r',t,y_out,'b',x,y,'ok');
            legend(app.UIAxes,'Funzione','Interpolazione Polinomiale a tratti','Punti')
            title(app.UIAxes,'Interpolazione polinomiale a tratti')
            
        case 'spline_not_a_knot'


            % La funzione spline fa un interpolazione not a knot cubica
            y_out = spline(x,y,t);
            
             plot(app.UIAxes,t,y1,'r',t,y_out,'b',x,y,'ok');
            legend(app.UIAxes,'Funzione','Interpolazione Spline not-a-knot','Punti')
            title(app.UIAxes,'Interpolazione Spline not-a-knot')
            
       case 'spline_naturale'


            % Per poter interpolare utilizzando gli altri tipi di spline (naturale o completa) dobbiamo richiamare quest'altra routine di matlab, csape.
% Funzione come spline, in x e y i dati che abbiamo a disposizione, e l'output è pp-form, non possiamo inserire come nella spline i nodi dove valutare la funzione interpolante ottenuta.
            pp = csape(x,y,'second')
            y_out = ppval(pp,t);
            
             plot(app.UIAxes,t,y1,'r',t,y_out,'b',x,y,'ok');
            legend(app.UIAxes,'Funzione','Interpolazione Spline Naturale','Punti')
            title(app.UIAxes,'Interpolazione Spline Naturale')
     
        case 'spline_completa'
         
            
            % A seconda del parametro che passo ottengo spline naturale
            % (prima) o completa (ora). La spline completa però prevede di avere in input alcuni valori, 
            % che sono r il valore della derivata in x1, e s valore della
            % derivata in xn, e in questo caso sono posti a 0 per
            % generalizzare.
%             
            pp = csape(x,[0 y 0],'clamped')
            y_out = ppval(pp,t);
            
             plot(app.UIAxes,t,y1,'r',t,y_out,'b',x,y,'ok');
            legend(app.UIAxes,'Funzione','Interpolazione Spline Completa','Punti')
            title(app.UIAxes,'Interpolazione Spline Completa')
            
    end
%       infine calcoliamo l'errore, come differenza in norma infinita tra
%       uscita della funzione originaria e uscita della funzione
%       interpolata
     errore = norm(y_out-y1,inf);
end
            
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
          
           funzione = str2func(['@(x)',app.FunzioneEditField.Value]);
        
           if app.PolinomialeButton.Value == true
               tipo = 'polinomiale'
           end
             if app.PolinomialeatrattiButton.Value == true
               tipo = 'lineare'
             end
               if app.SplinenotaknotButton.Value == true
               tipo = 'spline_not_a_knot'
               end
                 if app.SplinenaturaleButton.Value == true
               tipo = 'spline_naturale'
                 end
                   if app.SplinecompletaButton.Value == true
               tipo = 'spline_completa'
                   end
                   
              a = app.intervalloEditField.Value
              b = app.bEditField.Value
              n_nodi = app.numeronodiEditField.Value
               
            
     errore = interpolazione(app,funzione,tipo,a,b,n_nodi)


%             errore = interpolazione(f,tipo,A,B,N_nodi)
           
            app.ErroreLabel.Visible = true
             app.Label.Text = sprintf("%d",errore)
            
%             
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterpolazioneUIFigure = uifigure('Visible', 'off');
            app.InterpolazioneUIFigure.Name = 'Interpolazione';
            app.UIAxes = uiaxes(app.InterpolazioneUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PLOTButton = uibutton(app.InterpolazioneUIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.FunzioneEditFieldLabel = uilabel(app.InterpolazioneUIFigure);
            app.FunzioneEditFieldLabel.Text = 'Funzione';
            app.FunzioneEditField = uieditfield(app.InterpolazioneUIFigure, 'text');
            app.TipoButtonGroup = uibuttongroup(app.InterpolazioneUIFigure);
            app.TipoButtonGroup.Title = 'Tipo';
            app.PolinomialeButton = uiradiobutton(app.TipoButtonGroup);
            app.PolinomialeButton.Text = 'Polinomiale';
            app.PolinomialeButton.Value = true;
            app.PolinomialeatrattiButton = uiradiobutton(app.TipoButtonGroup);
            app.PolinomialeatrattiButton.Text = 'Polinomiale a tratti';
            app.SplinenotaknotButton = uiradiobutton(app.TipoButtonGroup);
            app.SplinenotaknotButton.Text = 'Spline not-a-knot';
            app.SplinenaturaleButton = uiradiobutton(app.TipoButtonGroup);
            app.SplinenaturaleButton.Text = 'Spline naturale';
            app.SplinecompletaButton = uiradiobutton(app.TipoButtonGroup);
            app.SplinecompletaButton.Text = 'Spline completa';
            app.intervalloEditFieldLabel = uilabel(app.InterpolazioneUIFigure);
            app.intervalloEditFieldLabel.Text = 'intervallo';
            app.intervalloEditField = uieditfield(app.InterpolazioneUIFigure, 'numeric');
            app.numeronodiEditFieldLabel = uilabel(app.InterpolazioneUIFigure);
            app.numeronodiEditFieldLabel.Text = 'numero nodi';
            app.numeronodiEditField = uieditfield(app.InterpolazioneUIFigure, 'numeric');
            app.Label = uilabel(app.InterpolazioneUIFigure);
            app.Label.Text = '';
            app.ErroreLabel = uilabel(app.InterpolazioneUIFigure);
            app.ErroreLabel.Visible = 'off';
            app.ErroreLabel.Text = 'Errore';
            app.bEditField = uieditfield(app.InterpolazioneUIFigure, 'numeric');
            % Show the figure after all components are created
            app.InterpolazioneUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = applicazione

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterpolazioneUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterpolazioneUIFigure)
        end
    end
end