classdef applicazione < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlottinginterattivoUIFigure  matlab.ui.Figure
        CaricaimmagineButton         matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        DisegnapuntiButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CaricaimmagineButton
        function CaricaimmagineButtonPushed(app, event)
           [filename, pathname] = uigetfile({'*.*';'*.jpg';'*.png';'*.bmp'}, "Carica immagine"); %standard open file dialog box
            fullname = [pathname filename]; %pathname contiene il percorso, filename il nome del file
           
            try
                %Leggo l'immagine originale
                lettura_imm = imread(fullname); %imread legge l'immagine dal file specificato da fullname
                immagine = image(lettura_imm); %image mostra l'immagine
          
                hold on
                n_nodi = 0;  % inizializzo la variabile che indica il numero dei punti
                % inizializzo i vettori che conterranno le coordinate dei punti
                x = [];
                y = [];
                
               
                bottone = 1;  % inizializzo la variabile indicatrice della pressione del tasto sinistro del mouse
                while (bottone == 1)
                    [xPos, yPos, bottone] = ginput(1); % input grafico dal mouse 
                    if(bottone==1)
                    plot(xPos, yPos, 'ok'); % disegno il punto tramite un cerchietto (o) nero (k)
                    n_nodi = n_nodi + 1; % aumento il conteggio dei punti
                    % memorizzo il punto trovato
                    x = [x xPos];
                    y = [y yPos];
                    end
                end
                % definisco i parametri per effettuare la spline dei punti
                i = 1 : n_nodi; %iteratore
                t = linspace(1, n_nodi); %genera un vettore di riga con punti equidistanziati
                xp = spline(i, x, t);
                yp = spline(i, y, t);
                
                % rendo trasparente l'immagine
                set(immagine, 'alphadata', .2);
                plot(xp, yp, 'r');
                hold off
            catch
            end  
        end

        % Button pushed function: DisegnapuntiButton
        function DisegnapuntiButtonPushed(app, event)
            
            try
      
                lettura_imm = imread('SfondoBianco.jpg');
                immagine = image(lettura_imm);

                hold on
         
                n_nodi = 0;

                x = [];
                y = [];
                
              
                bottone = 1;
                while (bottone == 1)
                    [xPos, yPos, bottone] = ginput(1);
                    if(bottone==1)
             
                    plot(xPos, yPos, 'ok');
                  
                    n_nodi = n_nodi + 1;
                    
                    x = [x xPos];
                    y = [y yPos];
                    end
                end
                % definisco i parametri per effettuare la spline dei punti
                i = 1 : n_nodi;
                t = linspace(1, n_nodi);
                xp = spline(i, x, t);
                yp = spline(i, y, t);
                
                plot(xp, yp, 'r');
                hold off
            catch
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlottinginterattivoUIFigure = uifigure('Visible', 'off');
            app.PlottinginterattivoUIFigure.Name = 'Plotting interattivo';
            app.CaricaimmagineButton = uibutton(app.PlottinginterattivoUIFigure, 'push');
            app.CaricaimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @CaricaimmagineButtonPushed, true);
            app.CaricaimmagineButton.Text = 'Carica immagine';
            app.TextArea = uitextarea(app.PlottinginterattivoUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Scegli un''immagine dal tuo pc, inserisci dei nodi con il tasto sinistro del mouse per simulare il contorno e premi il tasto ''F'' per completare chiudendo la curva chiusa.'; 'L''applicazione utilizzerà i punti inseriti per tracciare una spline. '; 'Cliccare su ''Disegna punti'' se non si vuole un''immagine in background.'};
            app.DisegnapuntiButton = uibutton(app.PlottinginterattivoUIFigure, 'push');
            app.DisegnapuntiButton.ButtonPushedFcn = createCallbackFcn(app, @DisegnapuntiButtonPushed, true);
            app.DisegnapuntiButton.Text = 'Disegna punti';
            % Show the figure after all components are created
            app.PlottinginterattivoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = applicazione

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlottinginterattivoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlottinginterattivoUIFigure)
        end
    end
end