classdef applicazione < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        Image2                    matlab.ui.control.Image
        SelezionaimmagineButton   matlab.ui.control.Button
        TipodiInterpolazioneButtonGroup  matlab.ui.container.ButtonGroup
        BilineareButton           matlab.ui.control.RadioButton
        SplineButton              matlab.ui.control.RadioButton
        BicubicaButton            matlab.ui.control.RadioButton
        ImmagineoriginaleLabel    matlab.ui.control.Label
        ImmagineinterpolataLabel  matlab.ui.control.Label
        IngrandimentoSliderLabel  matlab.ui.control.Label
        IngrandimentoSlider       matlab.ui.control.Slider
    end

    
    methods (Access = public)
        
        function [NEW_I] = ingrandisci(app,p,tipo)
            
                   %Setto lo slider al p intero
               app.IngrandimentoSlider.Value = p;
               
            [filename, pathname] = uigetfile({'*.jpg';'*.png';'*.bmp'}, "Carica immagine");
            fullname = [pathname filename];

            imm = imread(fullname);
            % Leggiamo l'immagine
            
            immagine = imresize(imm,1/p);
            
            imwrite(immagine, strcat(pathname,'resized.jpg'));
            
% Salvataggio dimensioni originali

[r,c,v] = size(immagine);

% Salvataggio griglia originale
old_x = 1:c; % griglia originale
old_y = 1:r;
% Creiamo una griglia con dimensioni p volte più fitta
new_x = linspace(1,c,p*c)';
new_y = linspace(1,r,p*r);

% Preallochiamo le componenti RGB
NEW_I = zeros(p*r,p*c,v,'uint8');

% Interpolazione componenti RGB per costruire le immagini in tre modi
% diversi: via spline, interpolazione bilineare e bicubica.

%Valuto le funzioni interpolanti e salvo le 3 pagine della matrice NEW_I
%(immagine interpolata)

switch tipo
    case 'bilineare'
        for (i=1:3)
 NEW_I(:,:,i) = uint8(interp2(old_x,old_y,double(immagine(:,:,i)),new_x,new_y,'linear'));
        end 
 imwrite(NEW_I, strcat(pathname,'lineare.jpg'));
   app.Image2.ImageSource = NEW_I
    case 'spline'
        for (i=1:3)
    NEW_I(:,:,i) = uint8(interp2(old_x,old_y,double(immagine(:,:,i)),new_x,new_y,'spline'));
        end
    imwrite(NEW_I, strcat(pathname,'spline.jpg'));
    app.Image2.ImageSource = NEW_I
        
    case 'bicubica'
        for (i=1:3)
  NEW_I(:,:,i) = uint8(interp2(old_x,old_y,double(immagine(:,:,i)),new_x,new_y,'cubic'));
        end
  imwrite(NEW_I, strcat(pathname,'bicubica.jpg'));
app.Image2.ImageSource = NEW_I
end

app.Image.ImageSource = immagine;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelezionaimmagineButton
        function SelezionaimmagineButtonPushed(app, event)
            
             if app.BilineareButton.Value == true
               tipo = 'bilineare'
           end
             if app.SplineButton.Value == true
               tipo = 'spline'
             end
               if app.BicubicaButton.Value == true
               tipo = 'bicubica'
               end
               
               %Valore intero
               p = round(app.IngrandimentoSlider.Value);
        
NEW_I = ingrandisci(app,p,tipo);
app.Image.Visible = true
app.Image2.Visible = true
app.ImmagineoriginaleLabel.Visible = true
app.ImmagineinterpolataLabel.Visible = true


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Visible = 'off';
            app.SelezionaimmagineButton = uibutton(app.UIFigure, 'push');
            app.SelezionaimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @SelezionaimmagineButtonPushed, true);
            app.SelezionaimmagineButton.Text = 'Seleziona immagine';
            app.TipodiInterpolazioneButtonGroup = uibuttongroup(app.UIFigure);
            app.TipodiInterpolazioneButtonGroup.Title = 'Tipo di Interpolazione';
            app.BilineareButton = uiradiobutton(app.TipodiInterpolazioneButtonGroup);
            app.BilineareButton.Text = 'Bilineare';
            app.BilineareButton.Value = true;
            app.SplineButton = uiradiobutton(app.TipodiInterpolazioneButtonGroup);
            app.SplineButton.Text = 'Spline';
            app.BicubicaButton = uiradiobutton(app.TipodiInterpolazioneButtonGroup);
            app.BicubicaButton.Text = 'Bicubica';
            app.ImmagineoriginaleLabel = uilabel(app.UIFigure);
            app.ImmagineoriginaleLabel.Visible = 'off';
            app.ImmagineoriginaleLabel.Text = 'Immagine originale';
            app.ImmagineinterpolataLabel = uilabel(app.UIFigure);
            app.ImmagineinterpolataLabel.Visible = 'off';
            app.ImmagineinterpolataLabel.Text = 'Immagine interpolata';
            app.IngrandimentoSliderLabel = uilabel(app.UIFigure);
            app.IngrandimentoSliderLabel.Text = {'Ingrandimento'; ''};
            app.IngrandimentoSlider = uislider(app.UIFigure);
            app.IngrandimentoSlider.Limits = [0 20];
            app.IngrandimentoSlider.MajorTicks = [0 2 5 10 20];
            app.IngrandimentoSlider.MinorTicks = [0 1 2 3 4 5 7 9 10 12 14 16 18];
            app.IngrandimentoSlider.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = applicazione

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end